classdef ManipulatorX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        INVERSEPanel          matlab.ui.container.Panel
        PzEditFieldLabel_3    matlab.ui.control.Label
        Pitch_Inv             matlab.ui.control.NumericEditField
        PLANNINGButton        matlab.ui.control.Button
        Pz_Inv                matlab.ui.control.NumericEditField
        Py_Inv                matlab.ui.control.NumericEditField
        Px_Inv                matlab.ui.control.NumericEditField
        PzEditFieldLabel_2    matlab.ui.control.Label
        PyEditFieldLabel_2    matlab.ui.control.Label
        PxEditFieldLabel_2    matlab.ui.control.Label
        INVERSEButton         matlab.ui.control.Button
        ENDEFFECTORPanel      matlab.ui.container.Panel
        Yaw_EE                matlab.ui.control.NumericEditField
        YawEditFieldLabel     matlab.ui.control.Label
        Roll_EE               matlab.ui.control.NumericEditField
        RollEditFieldLabel    matlab.ui.control.Label
        Pitch_EE              matlab.ui.control.NumericEditField
        PitchEditFieldLabel   matlab.ui.control.Label
        Z_EE                  matlab.ui.control.NumericEditField
        PzEditFieldLabel      matlab.ui.control.Label
        Y_EE                  matlab.ui.control.NumericEditField
        PyEditFieldLabel      matlab.ui.control.Label
        X_EE                  matlab.ui.control.NumericEditField
        PxEditFieldLabel      matlab.ui.control.Label
        FORWARDPanel          matlab.ui.container.Panel
        WorkSpaceCheckBox     matlab.ui.control.CheckBox
        Coordinate_Checkbox   matlab.ui.control.CheckBox
        Clear                 matlab.ui.control.Button
        RESETButton           matlab.ui.control.Button
        Theta4degSliderLabel  matlab.ui.control.Label
        FORWARDButton         matlab.ui.control.Button
        Theta4EditField       matlab.ui.control.NumericEditField
        Theta3EditField       matlab.ui.control.NumericEditField
        Theta2EditField       matlab.ui.control.NumericEditField
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta4Slider          matlab.ui.control.Slider
        Theta3Slider          matlab.ui.control.Slider
        Theta3degLabel        matlab.ui.control.Label
        Theta2Slider          matlab.ui.control.Slider
        Theta2degSliderLabel  matlab.ui.control.Label
        Theta1Slider          matlab.ui.control.Slider
        Theta1degSliderLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private) 
%% ======== các thông số ========
        % Khai báo các ma trận chuyển đổi    
        T01 = zeros(4);             
        T1a = zeros(4);             
        Ta2 = zeros(4);             
        T23 = zeros(4);             
        T34 = zeros(4);             
        T0a = zeros(4);             
        T02 = zeros(4);             
        T03 = zeros(4);             
        T04 = zeros(4);             
        
        % Tham số D-H (Denavit-Hartenberg) của các khớp
        theta = [   0     pi/2     -pi/2     0    0];
        d     = [   77     0      0     0     0];
        a     = [   0     128    24    124   126];
        alpha = [   pi/2    0     0    0    0];

        % -------- Góc quay của các khớp --------
        old_theta = [0, 0, 0, 0];           % Góc quay trước đó 
        new_theta = [0, 0, 0, 0];           % Góc quay hiện tại
        theta_offset_deg = [0, 90, -90, 0]; % offset giữa GUI và góc thực (độ)
        
        % Ma trận xoay theo trục y 
        Rotate_Y = [cos(pi/2)       0       sin(pi/2)       0   ; 
                    0               1       0               0   ; 
                    -sin(pi/2)      0       cos(pi/2)       0   ; 
                    0               0       0               1   ];
        
        % Biến vẽ đường đi 
        robotPath;
        % Biến lưu trữ kết quả các góc Euler (deg)
        eulerAngles = []; 

        % -------- Các trục và nhãn --------
        base_X;             base_Xlabel;            
        base_Y;             base_Ylabel;            
        base_Z;             base_Zlabel;            
        
        joint1_X;           joint1_Xlabel; 
        joint1_Y;           joint1_Ylabel;
        joint1_Z;           joint1_Zlabel;  
        
        jointa_X;           jointa_Xlabel;          
        jointa_Y;           jointa_Ylabel;         
        jointa_Z;           jointa_Zlabel;  
          
        joint2_X;           joint2_Xlabel;          
        joint2_Y;           joint2_Ylabel;          
        joint2_Z;           joint2_Zlabel;          
        
        joint3_X;           joint3_Xlabel;          
        joint3_Y;           joint3_Ylabel;         
        joint3_Z;           joint3_Zlabel;  
        
        joint4_X;           joint4_Xlabel;          
        joint4_Y;           joint4_Ylabel;         
        joint4_Z;           joint4_Zlabel;

        % Dùng cho inverse kinematics
        Px = 0; Py = 0; Pz = 0;  
        Pitch = 0;

    end
  
    methods (Access = private)
        function T = DH_Matrix(app, n)
                 T =       [cos(app.theta(n)),  -cos(app.alpha(n))*sin(app.theta(n)),  sin(app.alpha(n))*sin(app.theta(n)), app.a(n)*cos(app.theta(n));
                           sin(app.theta(n)),   cos(app.alpha(n))*cos(app.theta(n)), -sin(app.alpha(n))*cos(app.theta(n)), app.a(n)*sin(app.theta(n));
                           0,                   sin(app.alpha(n)),                     cos(app.alpha(n)),                     app.d(n);
                           0,                   0,                                     0,                                     1                        ];
        end
        
        function Draw_Robot(app)
            % Xóa hình ảnh robot cũ trên trục tọa độ
            hold(app.UIAxes, "off");
    
            % Các ma trận biến đổi Denavit-Hartenberg
            app.T01 = DH_Matrix(app,1);
            app.T1a = DH_Matrix(app,2);
            app.Ta2 = DH_Matrix(app,3);
            app.T23 = DH_Matrix(app,4);
            app.T34 = DH_Matrix(app,5);
           
            app.T0a = app.T01*app.T1a;
            app.T02 = app.T0a*app.Ta2;
            app.T03 = app.T02*app.T23;
            app.T04 = app.T03*app.T34;
 
            %% Base
            % TỐI ƯU: Giảm số phân đoạn cylinder từ 50 xuống 20 để vẽ nhanh hơn
            [X, Y, Z] = cylinder(30, 20); 
            Z = Z * 40;                  
            surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.00 0.45 0.74]); 
            hold(app.UIAxes, "on");
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.00 0.45 0.74]); 
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.00 0.45 0.74]); 

            % Trục khớp 1 
            [X, Y, Z] = cylinder(10, 20); % Tối ưu: 20 phân đoạn
            Z = Z * 77;
            surf(app.UIAxes, X, Y, Z, 'FaceColor', 'blue');
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'blue'); 
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'blue'); 

            % Hộp đế
            L = 60; W = 80; H = 40;
            x1 = -L; x2 = L/2;
            y1 = -W/2; y2 = W/2;
            z1 = 0;    z2 = H;

            % Tạo lưới cho 4 mặt bên
            [X1, Z1] = meshgrid([x1 x2], [z1 z2]); Y1 = y1 * ones(size(X1));
            [X2, Z2] = meshgrid([x1 x2], [z1 z2]); Y2 = y2 * ones(size(X2));
            [Y3, Z3] = meshgrid([y1 y2], [z1 z2]); X3 = x1 * ones(size(Y3));
            [Y4, Z4] = meshgrid([y1 y2], [z1 z2]); X4 = x2 * ones(size(Y4));
            
            surf(app.UIAxes, X1, Y1, Z1, 'FaceColor', [0.00 0.45 0.74]); 
            surf(app.UIAxes, X2, Y2, Z2, 'FaceColor', [0.00 0.45 0.74]);
            surf(app.UIAxes, X3, Y3, Z3, 'FaceColor', [0.00 0.45 0.74]);
            surf(app.UIAxes, X4, Y4, Z4, 'FaceColor', [0.00 0.45 0.74]);
            
            fill3(app.UIAxes, [x1 x2 x2 x1], [y1 y1 y2 y2], [z1 z1 z1 z1], [0.00 0.45 0.74]);
            fill3(app.UIAxes, [x1 x2 x2 x1], [y1 y1 y2 y2], [z2 z2 z2 z2], [0.00 0.45 0.74]);

            %% --------------- Vẽ Joint 1 --------------- 
            [X, Y, Z] = cylinder(20, 20); % Tối ưu: 20 phân đoạn
            Z = Z * 50 - 25;              

            A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))];
            B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
            A_new = app.T01 * A; B_new = app.T01 * B;
            X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
            Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
            Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
            
            surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.50,0.50,0.50]);
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.50,0.50,0.50]); 
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.50,0.50,0.50]); 

             %% --------------- Vẽ Joint 2 (Khối hộp + Cylinder) --------------- 
            L = 40; W = 40; H = 40;
            x = [-H/2 H/2 H/2 -H/2 -H/2 H/2 H/2 -H/2];
            y = [-W/2 -W/2 W/2 W/2 -W/2 -W/2 W/2 W/2];
            z = [-L/2 -L/2 -L/2 -L/2  L/2  L/2  L/2  L/2];
            
            P = [x; y; z; ones(1,8)];
            P_new = app.T0a * P;
            x_new = P_new(1, :); y_new = P_new(2, :); z_new = P_new(3, :);
            
            faces = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8];
            patch(app.UIAxes, 'Vertices', [x_new' y_new' z_new'], 'Faces', faces, ...
                'FaceColor', [0.00,0.45,0.74], 'EdgeColor', 'black');
            
            [X, Y, Z] = cylinder(20, 20); % Tối ưu: 20 phân đoạn
            Z = Z * 50 - 25;               

            A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
            B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
            A_new = app.T02 * A; B_new = app.T02 * B;
            X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
            Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
            Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);

            surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.93,0.69,0.13]);
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.93,0.69,0.13]); 
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.93,0.69,0.13]); 

            %% --------------- Vẽ Joint 3 --------------- 
            [X, Y, Z] = cylinder(20, 20); % Tối ưu
            Z = Z * 50 - 25;               

            A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
            B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
            A_new = app.T03 * A; B_new = app.T03 * B;
            X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
            Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
            Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);

            surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.00,0.45,0.74]);
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.80,0.80,0.80]);
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.80,0.80,0.80]); 

            %% --------------- Vẽ End-effector --------------- 
            x_e = [ 5,  50,   40,   30,   20,   -20,  -30,   -20,   20,   30,   40,   50,   5];
            z_e = [-10,  -10,  -20,  -20,  -30,  -40,   0,    40,   30,   20,   20,   10,   10];
            y_e = [  10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10 ; ...
                   -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10];

            x_e = repmat(x_e, 2, 1); 
            z_e = repmat(z_e, 2, 1); 
            
            A = [x_e(1, :); y_e(1, :); z_e(1, :); ones(1, size(x_e, 2))];  
            B = [x_e(2, :); y_e(2, :); z_e(2, :); ones(1, size(x_e, 2))]; 
            A_new = app.T04 * A; B_new = app.T04 * B;        
            
            x_e(1, :) = A_new(1, :); y_e(1, :) = A_new(2, :);        
            x_e(2, :) = B_new(1, :); y_e(2, :) = B_new(2, :);
            z_e(1, :) = A_new(3, :); z_e(2, :) = B_new(3, :);
            
            surf(app.UIAxes, x_e, y_e, z_e, 'FaceColor', [0.93,0.69,0.13]);
            fill3(app.UIAxes, x_e(1, :), y_e(1, :), z_e(1, :), [0.00,0.45,0.74]); 
            fill3(app.UIAxes, x_e(2, :), y_e(2, :), z_e(2, :), [0.00,0.45,0.74]); 

            % Tính điểm EE hiển thị
            P_ee = app.T04 * [50; 0; 0; 1];
            
            app.X_EE.Value = P_ee(1);
            app.Y_EE.Value = P_ee(2);
            app.Z_EE.Value = P_ee(3);

            % Vẽ đường đi
            [~, n] = size(app.robotPath);
            app.robotPath(1, n + 1) = P_ee(1);
            app.robotPath(2, n + 1) = P_ee(2);
            app.robotPath(3, n + 1) = P_ee(3);  
            plot3(app.UIAxes, app.robotPath(1, :), app.robotPath(2, :), app.robotPath(3, :), 'LineWidth', 2, 'Color', 'b');
            
            %% ====== Vẽ workspace ======
            if isprop(app,'WorkSpaceCheckBox') && app.WorkSpaceCheckBox.Value
                r = 380; z_center = 205;
                % TỐI ƯU: Giảm lưới meshgrid từ 40x40 xuống 20x20
                [u, v] = meshgrid(linspace(0, 2*pi, 20), linspace(0, pi, 20));
                x = r .* cos(u) .* sin(v);
                y = r .* sin(u) .* sin(v);
                z = r .* cos(v) + z_center;

                hold(app.UIAxes, "on");
                surf(app.UIAxes, x, y, z, 'EdgeColor', 'none', 'FaceColor', [0.5 0.5 0.5], 'FaceAlpha', 0.15);
            end

            %% TÍNH TOÁN GÓC EULER
            cospitch = sqrt((app.T04(1, 1))^2 + (app.T04(2, 1))^2);    
            pitch = atan2(-app.T04(3, 1), cospitch);                   

            if pitch == pi / 2          
                yaw = 0; roll = atan2(app.T04(1, 2), app.T04(2, 2));             
            elseif pitch == -pi / 2
                yaw = 0; roll = atan2(-app.T04(1, 2), app.T04(2, 2));            
            else
                yaw = atan2(app.T04(2, 1) / cos(pitch), app.T04(1, 1) / cos(pitch));    
                roll = atan2(app.T04(3, 2) / cos(pitch), app.T04(3, 3) / cos(pitch));  
            end
            app.eulerAngles = [roll pitch yaw] * (180 / pi); 

            app.Roll_EE.Value    = app.eulerAngles(1);
            app.Pitch_EE.Value   = app.eulerAngles(2);
            app.Yaw_EE.Value     = app.eulerAngles(3);  
            
            % Cập nhật trục tọa độ
            app.Coordinate_CheckboxValueChanged();

             %% --------------- Vẽ Link 1 --------------- 
            [X, Y, Z] = cylinder(10, 10);  % Tối ưu: 10 phân đoạn
            Z = Z * 128 - 128;            

            A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
            B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
            A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
            A_new = app.T0a * A_new; B_new = app.T0a * B_new;
            X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
            Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
            Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);
            
            surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.80,0.80,0.80]);
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.80,0.80,0.80]);
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.80,0.80,0.80]); 

             %% --------------- Vẽ Link 2 --------------- 
            [X, Y, Z] = cylinder(10, 10); 
            Z = Z * 124 - 124;            

            A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
            B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
            A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
            A_new = app.T03 * A_new; B_new = app.T03 * B_new;
            X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
            Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
            Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);

            surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.64,0.08,0.18]);
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.64,0.08,0.18]);
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.64,0.08,0.18]); 
            
            %% --------------- Vẽ Link 3 --------------- 
            [X, Y, Z] = cylinder(10, 10);  
            Z = Z * 126-126 ;            

            A = [X(1, :); Y(1, :); Z(1, :); ones(1, size(X, 2))]; 
            B = [X(2, :); Y(2, :); Z(2, :); ones(1, size(X, 2))];
            A_new = app.Rotate_Y * A; B_new = app.Rotate_Y * B;
            A_new = app.T04 * A_new; B_new = app.T04 * B_new;
            X(1, :) = A_new(1, :); X(2, :) = B_new(1, :);
            Y(1, :) = A_new(2, :); Y(2, :) = B_new(2, :);
            Z(1, :) = A_new(3, :); Z(2, :) = B_new(3, :);

            surf(app.UIAxes, X, Y, Z, 'FaceColor', [0.80,0.80,0.80]);
            fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), [0.93,0.69,0.13]); 
            fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), [0.93,0.69,0.13]); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Góc ban đầu của robot (rad): [0, 90, -90, 0]
            app.theta(1) = deg2rad(app.theta_offset_deg(1)); % 0
            app.theta(2) = deg2rad(app.theta_offset_deg(2)); % 90
            app.theta(3) = deg2rad(app.theta_offset_deg(3)); % -90
            app.theta(4) = deg2rad(app.theta_offset_deg(4)); % 0

            app.old_theta = app.theta(1:4);
            app.robotPath = [];
            
            app.Theta1Slider.Value = 0;
            app.Theta2Slider.Value = 0;
            app.Theta3Slider.Value = 0;
            app.Theta4Slider.Value = 0;

            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0 ;
            app.Theta3EditField.Value = 0 ;
            app.Theta4EditField.Value = 0 ;

            app.Draw_Robot();

        end

        % Button pushed function: FORWARDButton
        function FORWARDButtonPushed(app, event)
            app.old_theta = app.theta(1:4);

            % Joint 1
            ui1 = app.Theta1Slider.Value;
            app.Theta1EditField.Value = ui1;
            th1_real_deg = ui1 + app.theta_offset_deg(1);
            app.new_theta(1) = deg2rad(th1_real_deg);

            % Joint 2
            ui2 = app.Theta2Slider.Value;
            app.Theta2EditField.Value = ui2;
            th2_real_deg = ui2 + app.theta_offset_deg(2);
            app.new_theta(2) = deg2rad(th2_real_deg);

            % Joint 3
            ui3 = app.Theta3Slider.Value;
            app.Theta3EditField.Value = ui3;
            th3_real_deg = ui3 + app.theta_offset_deg(3);
            app.new_theta(3) = deg2rad(th3_real_deg);

            % Joint 4
            ui4 = app.Theta4Slider.Value;
            app.Theta4EditField.Value = ui4;
            th4_real_deg = ui4 + app.theta_offset_deg(4);
            app.new_theta(4) = deg2rad(th4_real_deg);

            %% ================== Rời rạc hóa theta =======================
            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(5) = DiscreteTheta4(i);
                app.Draw_Robot();
                pause(0.05);
            end
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
                ui1 = app.Theta1Slider.Value;
                app.Theta1EditField.Value = ui1;
                th1_real_deg = ui1 + app.theta_offset_deg(1);
                app.new_theta(1) = deg2rad(th1_real_deg);
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
                ui2 = app.Theta2Slider.Value;
                app.Theta2EditField.Value = ui2;
                th2_real_deg = ui2 + app.theta_offset_deg(2);
                app.new_theta(2) = deg2rad(th2_real_deg);

        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
                ui3 = app.Theta3Slider.Value;
                app.Theta3EditField.Value = ui3;
                th3_real_deg = ui3 + app.theta_offset_deg(3);
                app.new_theta(3) = deg2rad(th3_real_deg);
        end

        % Value changed function: Theta4Slider
        function Theta4SliderValueChanged(app, event)
                ui4 = app.Theta4Slider.Value;
                app.Theta4EditField.Value = ui4;
                th4_real_deg = ui4 + app.theta_offset_deg(4);
                app.new_theta(4) = deg2rad(th4_real_deg);
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.old_theta = app.theta(1:4);

            for j = 1:4
                ui = 0; % GUI luôn về 0
                real_deg = ui + app.theta_offset_deg(j);  
                app.new_theta(j) = deg2rad(real_deg);

                switch j
                    case 1
                        app.Theta1Slider.Value = ui;
                        app.Theta1EditField.Value = ui;
                    case 2
                        app.Theta2Slider.Value = ui;
                        app.Theta2EditField.Value = ui;
                    case 3
                        app.Theta3Slider.Value = ui;
                        app.Theta3EditField.Value = ui;
                    case 4
                        app.Theta4Slider.Value = ui;
                        app.Theta4EditField.Value = ui;
                end
            end

            DiscreteTheta1 = linspace(app.old_theta(1), app.new_theta(1), 20);       
            DiscreteTheta2 = linspace(app.old_theta(2), app.new_theta(2), 20);       
            DiscreteTheta3 = linspace(app.old_theta(3), app.new_theta(3), 20);       
            DiscreteTheta4 = linspace(app.old_theta(4), app.new_theta(4), 20);       

            for i=1:20
                app.theta(1) = DiscreteTheta1(i);
                app.theta(2) = DiscreteTheta2(i);
                app.theta(3) = DiscreteTheta3(i);
                app.theta(5) = DiscreteTheta4(i);
                app.Draw_Robot();
                pause(0.05);
            end
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.robotPath = [];
            app.Draw_Robot();
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            ui1 = app.Theta1EditField.Value;
            app.Theta1Slider.Value = ui1;
            th1_real_deg = ui1 + app.theta_offset_deg(1);
            app.new_theta(1) = deg2rad(th1_real_deg);
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            ui1 = app.Theta2EditField.Value;
            app.Theta2Slider.Value = ui1;
            th1_real_deg = ui1 + app.theta_offset_deg(1);
            app.new_theta(1) = deg2rad(th1_real_deg);
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            ui3 = app.Theta3EditField.Value;
            app.Theta3Slider.Value = ui3;
            th3_real_deg = ui3 + app.theta_offset_deg(3);
            app.new_theta(3) = deg2rad(th3_real_deg);
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            ui4 = app.Theta4EditField.Value;
            app.Theta4Slider.Value = ui4;
            th4_real_deg = ui4 + app.theta_offset_deg(4);
            app.new_theta(4) = deg2rad(th4_real_deg);
        end

        % Value changed function: Coordinate_Checkbox
        function Coordinate_CheckboxValueChanged(app, event)
value = app.Coordinate_Checkbox.Value;
            if value 
                % --- Vẽ các trục tọa độ x y z cho BASE ---
                x_axis = [100 0 0 1]'; y_axis = [0 100 0 1]'; z_axis = [0 0 100 1]'; origin = [0 0 0 1]'; 
                
                app.base_X = quiver3(app.UIAxes, origin(1), origin(2), origin(3), x_axis(1)-origin(1), x_axis(2)-origin(2), x_axis(3)-origin(3), 'r', 'LineWidth', 2);
                app.base_Y = quiver3(app.UIAxes, origin(1), origin(2), origin(3), y_axis(1)-origin(1), y_axis(2)-origin(2), y_axis(3)-origin(3), 'g', 'LineWidth', 2);
                app.base_Z = quiver3(app.UIAxes, origin(1), origin(2), origin(3), z_axis(1)-origin(1), z_axis(2)-origin(2), z_axis(3)-origin(3), 'b', 'LineWidth', 2);
                
                app.base_Xlabel = text(app.UIAxes, x_axis(1), x_axis(2), x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.base_Ylabel = text(app.UIAxes, y_axis(1), y_axis(2), y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.base_Zlabel = text(app.UIAxes, z_axis(1), z_axis(2), z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');

                % --- Vẽ JOINT 1 ---
                new_x = app.T01 * x_axis; new_y = app.T01 * y_axis; new_z = app.T01 * z_axis; new_o = app.T01 * origin;
                
                app.joint1_X = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_x(1)-new_o(1), new_x(2)-new_o(2), new_x(3)-new_o(3), 'r', 'LineWidth', 2);
                app.joint1_Y = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_y(1)-new_o(1), new_y(2)-new_o(2), new_y(3)-new_o(3), 'g', 'LineWidth', 2);
                app.joint1_Z = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_z(1)-new_o(1), new_z(2)-new_o(2), new_z(3)-new_o(3), 'b', 'LineWidth', 2);
                
                app.joint1_Xlabel = text(app.UIAxes, new_x(1), new_x(2), new_x(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint1_Ylabel = text(app.UIAxes, new_y(1), new_y(2), new_y(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint1_Zlabel = text(app.UIAxes, new_z(1), new_z(2), new_z(3), 'Z', 'FontSize', 12, 'Color', 'b');

                % --- Vẽ JOINT A ---
                new_x = app.T0a * x_axis; new_y = app.T0a * y_axis; new_z = app.T0a * z_axis; new_o = app.T0a * origin;
                
                app.jointa_X = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_x(1)-new_o(1), new_x(2)-new_o(2), new_x(3)-new_o(3), 'r', 'LineWidth', 2);
                app.jointa_Y = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_y(1)-new_o(1), new_y(2)-new_o(2), new_y(3)-new_o(3), 'g', 'LineWidth', 2);
                app.jointa_Z = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_z(1)-new_o(1), new_z(2)-new_o(2), new_z(3)-new_o(3), 'b', 'LineWidth', 2);
                
                app.jointa_Xlabel = text(app.UIAxes, new_x(1), new_x(2), new_x(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.jointa_Ylabel = text(app.UIAxes, new_y(1), new_y(2), new_y(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.jointa_Zlabel = text(app.UIAxes, new_z(1), new_z(2), new_z(3), 'Z', 'FontSize', 12, 'Color', 'b');

                % --- Vẽ JOINT 2 ---
                new_x = app.T02 * x_axis; new_y = app.T02 * y_axis; new_z = app.T02 * z_axis; new_o = app.T02 * origin;
                
                app.joint2_X = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_x(1)-new_o(1), new_x(2)-new_o(2), new_x(3)-new_o(3), 'r', 'LineWidth', 2);
                app.joint2_Y = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_y(1)-new_o(1), new_y(2)-new_o(2), new_y(3)-new_o(3), 'g', 'LineWidth', 2);
                app.joint2_Z = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_z(1)-new_o(1), new_z(2)-new_o(2), new_z(3)-new_o(3), 'b', 'LineWidth', 2);
                
                app.joint2_Xlabel = text(app.UIAxes, new_x(1), new_x(2), new_x(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint2_Ylabel = text(app.UIAxes, new_y(1), new_y(2), new_y(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint2_Zlabel = text(app.UIAxes, new_z(1), new_z(2), new_z(3), 'Z', 'FontSize', 12, 'Color', 'b');

                % --- Vẽ JOINT 3 ---
                new_x = app.T03 * x_axis; new_y = app.T03 * y_axis; new_z = app.T03 * z_axis; new_o = app.T03 * origin;
                
                app.joint3_X = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_x(1)-new_o(1), new_x(2)-new_o(2), new_x(3)-new_o(3), 'r', 'LineWidth', 2);
                app.joint3_Y = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_y(1)-new_o(1), new_y(2)-new_o(2), new_y(3)-new_o(3), 'g', 'LineWidth', 2);
                app.joint3_Z = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_z(1)-new_o(1), new_z(2)-new_o(2), new_z(3)-new_o(3), 'b', 'LineWidth', 2);
                
                app.joint3_Xlabel = text(app.UIAxes, new_x(1), new_x(2), new_x(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint3_Ylabel = text(app.UIAxes, new_y(1), new_y(2), new_y(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint3_Zlabel = text(app.UIAxes, new_z(1), new_z(2), new_z(3), 'Z', 'FontSize', 12, 'Color', 'b');

                % --- Vẽ JOINT 4 (EE) ---
                new_x = app.T04 * x_axis; new_y = app.T04 * y_axis; new_z = app.T04 * z_axis; new_o = app.T04 * origin;
                
                app.joint4_X = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_x(1)-new_o(1), new_x(2)-new_o(2), new_x(3)-new_o(3), 'r', 'LineWidth', 2);
                app.joint4_Y = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_y(1)-new_o(1), new_y(2)-new_o(2), new_y(3)-new_o(3), 'g', 'LineWidth', 2);
                app.joint4_Z = quiver3(app.UIAxes, new_o(1), new_o(2), new_o(3), new_z(1)-new_o(1), new_z(2)-new_o(2), new_z(3)-new_o(3), 'b', 'LineWidth', 2);
                
                app.joint4_Xlabel = text(app.UIAxes, new_x(1), new_x(2), new_x(3), 'X', 'FontSize', 12, 'Color', 'r');
                app.joint4_Ylabel = text(app.UIAxes, new_y(1), new_y(2), new_y(3), 'Y', 'FontSize', 12, 'Color', 'g');
                app.joint4_Zlabel = text(app.UIAxes, new_z(1), new_z(2), new_z(3), 'Z', 'FontSize', 12, 'Color', 'b');

            else 
                % Xóa các trục và nhãn đã vẽ nếu checkbox không được chọn
                objects = {app.base_X, app.base_Y, app.base_Z, app.base_Xlabel, app.base_Ylabel, app.base_Zlabel, ...
                           app.joint1_X, app.joint1_Y, app.joint1_Z, app.joint1_Xlabel, app.joint1_Ylabel, app.joint1_Zlabel, ...
                           app.jointa_X, app.jointa_Y, app.jointa_Z, app.jointa_Xlabel, app.jointa_Ylabel, app.jointa_Zlabel, ...
                           app.joint2_X, app.joint2_Y, app.joint2_Z, app.joint2_Xlabel, app.joint2_Ylabel, app.joint2_Zlabel, ...
                           app.joint3_X, app.joint3_Y, app.joint3_Z, app.joint3_Xlabel, app.joint3_Ylabel, app.joint3_Zlabel, ...
                           app.joint4_X, app.joint4_Y, app.joint4_Z, app.joint4_Xlabel, app.joint4_Ylabel, app.joint4_Zlabel};
                for i = 1:length(objects)
                    try delete(objects{i}); catch; end % Try-catch để tránh lỗi nếu biến chưa tồn tại
                end
            end
        end

        % Value changed function: WorkSpaceCheckBox
        function WorkSpaceCheckBoxValueChanged(app, event)
            app.Draw_Robot();
        end

        % Button pushed function: INVERSEButton
        function INVERSEButtonPushed(app, event)
            % 1. CẤU HÌNH THAM SỐ ROBOT
            d1 = 77; L2 = 128; L3 = 24 + 124; L4 = 126 + 50;
            offsets = [0, 90, -90, 0]; 

            % 2. ĐỌC DỮ LIỆU
            px = app.Px_Inv.Value;
            py = app.Py_Inv.Value;
            pz = app.Pz_Inv.Value;
            pitch_val = app.Pitch_Inv.Value;

            % Input Pitch -90 (thẳng đứng) -> Tính toán +90
            phi = deg2rad(-pitch_val);

            % 3. XỬ LÝ GÓC ĐẾ & BÁN KÍNH
            theta1_raw = atan2(py, px);
            pr = sqrt(px^2 + py^2);

            if abs(theta1_raw) > pi/2
                if theta1_raw > 0, theta1 = theta1_raw - pi;
                else, theta1 = theta1_raw + pi;
                end
                pr = -pr; 
            else
                theta1 = theta1_raw;
            end

            % 4. TÍNH TOÁN IK
            r_ee = pr;      
            z_ee = pz - d1;
            r_wc = r_ee - L4 * cos(phi);
            z_wc = z_ee - L4 * sin(phi);

            numerator = r_wc^2 + z_wc^2 - L2^2 - L3^2;
            denominator = 2 * L2 * L3;
            D = numerator / denominator;

            if D > 1, D = 1; elseif D < -1, D = -1; end
            
            theta3_geom = -acos(D); 
            k1 = L2 + L3 * cos(theta3_geom);
            k2 = L3 * sin(theta3_geom);
            theta2_geom = atan2(z_wc, r_wc) - atan2(k2, k1);
            theta4_geom = phi - theta2_geom - theta3_geom;

            % 5. CẬP NHẬT KẾT QUẢ
            app.theta(1) = theta1;
            app.theta(2) = theta2_geom;
            app.theta(3) = theta3_geom;
            app.theta(5) = theta4_geom;

            app.Theta1EditField.Value = rad2deg(theta1) - offsets(1);
            app.Theta1Slider.Value    = rad2deg(theta1) - offsets(1);

            app.Theta2EditField.Value = rad2deg(theta2_geom) - offsets(2);
            app.Theta2Slider.Value    = rad2deg(theta2_geom) - offsets(2);

            app.Theta3EditField.Value = rad2deg(theta3_geom) - offsets(3);
            app.Theta3Slider.Value    = rad2deg(theta3_geom) - offsets(3);

            app.Theta4EditField.Value = rad2deg(theta4_geom) - offsets(4);
            app.Theta4Slider.Value    = rad2deg(theta4_geom) - offsets(4);

            % Vẽ lại robot
            app.new_theta(1) = app.theta(1);
            app.new_theta(2) = app.theta(2);
            app.new_theta(3) = app.theta(3);
            app.new_theta(4) = app.theta(5);
            app.Draw_Robot();
        end

        % Value changed function: Px_Inv
        function Px_InvValueChanged(app, event)
            app.Px = app.Px_Inv.Value;
  
        end

        % Value changed function: Py_Inv
        function Py_InvValueChanged(app, event)
            app.Py = app.Py_Inv.Value;
            
        end

        % Value changed function: Pz_Inv
        function Pz_InvValueChanged(app, event)
            app.Pz = app.Pz_Inv.Value - app.d(1);
            
        end

        % Value changed function: Pitch_Inv
        function Pitch_InvValueChanged(app, event)
            app.Pitch  = app.Pitch_Inv.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Manipulator X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [0 700];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.XTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.YTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.ZTick = [0 100 200 300 400 500 600];
            app.UIAxes.ZTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.FORWARDPanel = uipanel(app.UIFigure);
            app.FORWARDPanel.Title = 'FORWARD';
            app.Theta1degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta1degSliderLabel.Text = {'Theta1'; '(deg)'};
            app.Theta1Slider = uislider(app.FORWARDPanel);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta2degSliderLabel.Text = {'Theta2'; '(deg)'};
            app.Theta2Slider = uislider(app.FORWARDPanel);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3degLabel = uilabel(app.FORWARDPanel);
            app.Theta3degLabel.Text = {'Theta3'; '(deg)'};
            app.Theta3Slider = uislider(app.FORWARDPanel);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta4Slider = uislider(app.FORWARDPanel);
            app.Theta4Slider.Limits = [-180 180];
            app.Theta4Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta4SliderValueChanged, true);
            app.Theta1EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta1EditField.ValueDisplayFormat = '%.2f';
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta2EditField.ValueDisplayFormat = '%.2f';
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta3EditField.ValueDisplayFormat = '%.2f';
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta4EditField.ValueDisplayFormat = '%.2f';
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.FORWARDButton = uibutton(app.FORWARDPanel, 'push');
            app.FORWARDButton.ButtonPushedFcn = createCallbackFcn(app, @FORWARDButtonPushed, true);
            app.FORWARDButton.Text = 'FORWARD';
            app.Theta4degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta4degSliderLabel.Text = {'Theta4'; '(deg)'};
            app.RESETButton = uibutton(app.FORWARDPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Clear = uibutton(app.FORWARDPanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'CLEAR';
            app.Coordinate_Checkbox = uicheckbox(app.FORWARDPanel);
            app.Coordinate_Checkbox.ValueChangedFcn = createCallbackFcn(app, @Coordinate_CheckboxValueChanged, true);
            app.Coordinate_Checkbox.Tag = 'Coordinateall';
            app.Coordinate_Checkbox.Text = 'Coordinate axes';
            app.WorkSpaceCheckBox = uicheckbox(app.FORWARDPanel);
            app.WorkSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @WorkSpaceCheckBoxValueChanged, true);
            app.WorkSpaceCheckBox.Text = 'WorkSpace';
            app.ENDEFFECTORPanel = uipanel(app.UIFigure);
            app.ENDEFFECTORPanel.Title = 'END - EFFECTOR';
            app.PxEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PxEditFieldLabel.Text = 'Px';
            app.X_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.X_EE.ValueDisplayFormat = '%.2f';
            app.PyEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PyEditFieldLabel.Text = 'Py';
            app.Y_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Y_EE.ValueDisplayFormat = '%.2f';
            app.PzEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.Z_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Z_EE.ValueDisplayFormat = '%.2f';
            app.PitchEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.Pitch_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Pitch_EE.ValueDisplayFormat = '%.2f';
            app.RollEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.Roll_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Roll_EE.ValueDisplayFormat = '%.2f';
            app.YawEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.Yaw_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Yaw_EE.ValueDisplayFormat = '%.2f';
            app.INVERSEPanel = uipanel(app.UIFigure);
            app.INVERSEPanel.Title = 'INVERSE';
            app.INVERSEButton = uibutton(app.INVERSEPanel, 'push');
            app.INVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @INVERSEButtonPushed, true);
            app.INVERSEButton.Text = 'INVERSE';
            app.PxEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PxEditFieldLabel_2.Text = 'Px';
            app.PyEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PyEditFieldLabel_2.Text = 'Py';
            app.PzEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PzEditFieldLabel_2.Text = 'Pz';
            app.Px_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Px_Inv.ValueDisplayFormat = '%.2f';
            app.Px_Inv.ValueChangedFcn = createCallbackFcn(app, @Px_InvValueChanged, true);
            app.Py_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Py_Inv.ValueDisplayFormat = '%.2f';
            app.Py_Inv.ValueChangedFcn = createCallbackFcn(app, @Py_InvValueChanged, true);
            app.Pz_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pz_Inv.ValueDisplayFormat = '%.2f';
            app.Pz_Inv.ValueChangedFcn = createCallbackFcn(app, @Pz_InvValueChanged, true);
            app.PLANNINGButton = uibutton(app.INVERSEPanel, 'push');
            app.PLANNINGButton.Text = 'PLANNING';
            app.Pitch_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pitch_Inv.ValueDisplayFormat = '%.2f';
            app.Pitch_Inv.ValueChangedFcn = createCallbackFcn(app, @Pitch_InvValueChanged, true);
            app.PzEditFieldLabel_3 = uilabel(app.INVERSEPanel);
            app.PzEditFieldLabel_3.Text = 'Pitch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end